classdef EEG_Profile_Creator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGProfileCreatorUIFigure      matlab.ui.Figure
        PatientDropDownLabel           matlab.ui.control.Label
        PatientDropDown                matlab.ui.control.DropDown
        Image                          matlab.ui.control.Image
        TotalDurationhoursEditField_2Label  matlab.ui.control.Label
        TotalDurationhoursEditField_2  matlab.ui.control.NumericEditField
        NumberofSeizureEditFieldLabel  matlab.ui.control.Label
        NumberofSeizureEditField       matlab.ui.control.NumericEditField
        SeizureDurationsecondsEditFieldLabel  matlab.ui.control.Label
        SeizureDurationsecondsEditField  matlab.ui.control.NumericEditField
        SeizureMeansecondsEditFieldLabel  matlab.ui.control.Label
        SeizureMeansecondsEditField    matlab.ui.control.NumericEditField
        FP1F7CheckBox                  matlab.ui.control.CheckBox
        WindowsizeSliderLabel          matlab.ui.control.Label
        WindowsizeSlider               matlab.ui.control.Slider
        EditField                      matlab.ui.control.NumericEditField
        C3P3CheckBox                   matlab.ui.control.CheckBox
        FP2F8CheckBox                  matlab.ui.control.CheckBox
        F7T7CheckBox                   matlab.ui.control.CheckBox
        P3O1CheckBox                   matlab.ui.control.CheckBox
        F8T8CheckBox                   matlab.ui.control.CheckBox
        T7FT9CheckBox                  matlab.ui.control.CheckBox
        T7P7CheckBox                   matlab.ui.control.CheckBox
        FP2F4CheckBox                  matlab.ui.control.CheckBox
        T8P8CheckBox                   matlab.ui.control.CheckBox
        FT9FT10CheckBox                matlab.ui.control.CheckBox
        P7O1CheckBox                   matlab.ui.control.CheckBox
        F4C4CheckBox                   matlab.ui.control.CheckBox
        P8O2CheckBox                   matlab.ui.control.CheckBox
        FT10T8CheckBox                 matlab.ui.control.CheckBox
        F3C3CheckBox                   matlab.ui.control.CheckBox
        P4O2CheckBox                   matlab.ui.control.CheckBox
        CZPZCheckBox                   matlab.ui.control.CheckBox
        F7LampLabel                    matlab.ui.control.Label
        F7Lamp                         matlab.ui.control.Lamp
        FP1LampLabel                   matlab.ui.control.Label
        FP1Lamp                        matlab.ui.control.Lamp
        F3LampLabel                    matlab.ui.control.Label
        F3Lamp                         matlab.ui.control.Lamp
        T7LampLabel                    matlab.ui.control.Label
        T7Lamp                         matlab.ui.control.Lamp
        C3LampLabel                    matlab.ui.control.Label
        C3Lamp                         matlab.ui.control.Lamp
        FzLampLabel                    matlab.ui.control.Label
        FzLamp                         matlab.ui.control.Lamp
        FP2LampLabel                   matlab.ui.control.Label
        FP2Lamp                        matlab.ui.control.Lamp
        F8LampLabel                    matlab.ui.control.Label
        F8Lamp                         matlab.ui.control.Lamp
        F4LampLabel                    matlab.ui.control.Label
        F4Lamp                         matlab.ui.control.Lamp
        CzLampLabel                    matlab.ui.control.Label
        CzLamp                         matlab.ui.control.Lamp
        C4LampLabel                    matlab.ui.control.Label
        C4Lamp                         matlab.ui.control.Lamp
        T8LampLabel                    matlab.ui.control.Label
        T8Lamp                         matlab.ui.control.Lamp
        P8LampLabel                    matlab.ui.control.Label
        P8Lamp                         matlab.ui.control.Lamp
        P4LampLabel                    matlab.ui.control.Label
        P4Lamp                         matlab.ui.control.Lamp
        O2LampLabel                    matlab.ui.control.Label
        O2Lamp                         matlab.ui.control.Lamp
        PzLampLabel                    matlab.ui.control.Label
        PzLamp                         matlab.ui.control.Lamp
        O1LampLabel                    matlab.ui.control.Label
        O1Lamp                         matlab.ui.control.Lamp
        P3LampLabel                    matlab.ui.control.Label
        P3Lamp                         matlab.ui.control.Lamp
        P7LampLabel                    matlab.ui.control.Label
        P7Lamp                         matlab.ui.control.Lamp
        FT10LampLabel                  matlab.ui.control.Label
        FT10Lamp                       matlab.ui.control.Lamp
        FT9LampLabel                   matlab.ui.control.Label
        FT9Lamp                        matlab.ui.control.Lamp
        FP1F3CheckBox                  matlab.ui.control.CheckBox
        C4P4CheckBox                   matlab.ui.control.CheckBox
        FZCZCheckBox                   matlab.ui.control.CheckBox
        CreateProfileButton            matlab.ui.control.Button
        ChannelsLabel                  matlab.ui.control.Label
        EEGProfileCreatorLabel         matlab.ui.control.Label
        developbySonyaZadigolinVikiFadlonLabel  matlab.ui.control.Label
        FilternoisychannelsCheckBox    matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
        function modify_metadata(app,value)
            metatable = readtable('metadata.txt');
            index = strcmp(metatable.Var1,value);
            app.TotalDurationhoursEditField_2.Value = metatable.Var2(index);
            app.NumberofSeizureEditField.Value = metatable.Var3(index);
            app.SeizureDurationsecondsEditField.Value = metatable.Var4(index);
            app.SeizureMeansecondsEditField.Value = metatable.Var5(index);
        end
        
        function results = colorchange(~,value,event)
            if event == true
                results = value - 0.25;
            else
                results = value + 0.25;
            end
            
        end
        
        function [result,file_name] = EEG_Profile(app,patient,channels,window_size)
            d = uiprogressdlg(app.EEGProfileCreatorUIFigure,'Title','Creating EEG Profile','Message','1','Cancelable','on');
            d.Value = 0.25;
            d.Message = sprintf('Load files from %S:',patient);
            EEG = load_files(patient, channels);
            d.Value = 0.5;
            d.Message = sprintf('split_EEG dataset');
            [EEG_train,EEG_test] = split_EEG(EEG);
            if app.FilternoisychannelsCheckBox.Value
                d.Value = 0.65;
                d.Message = sprintf('Filter noisy channels');
                [EEG_train_no_noisy_ch,noisy_ch_index] = Remove_noisy(EEG_train, channels);
                ch_index = sum(noisy_ch_index)>(length(EEG_train)/2);
                channels_no_noisy = channels;
                channels_no_noisy(ch_index) = [];
            else
                EEG_train_no_noisy_ch = EEG_train;
                channels_no_noisy = channels;
            end
            d.Value = 0.75;
            d.Message = sprintf('Extract dataset');
            dataset_train = extract_dataset(EEG_train_no_noisy_ch ,channels_no_noisy,window_size);
            [dataset_test,len_test] = extract_dataset_test(EEG_test ,channels_no_noisy,window_size);
            d.Value = 0.75;
            d.Message = sprintf('Analyze dataset');
            [result] = analyze_datasets(dataset_train,dataset_test,len_test);
            d.Value = 1;
            d.Message = sprintf('Plot results');
            [file_name] = plot_result(result,patient,window_size,channels,channels_no_noisy);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            modify_metadata(app,'chb01');
            app.F7Lamp.Color = 'white';
            app.FP1Lamp.Color = 'white';
            app.F3Lamp.Color = 'white';
            app.T7Lamp.Color = 'white';
            app.C3Lamp.Color = 'white';
            app.FzLamp.Color = 'white';
            app.FP2Lamp.Color = 'white';
            app.F8Lamp.Color = 'white';
            app.F4Lamp.Color = 'white';
            app.CzLamp.Color = 'white';
            app.C4Lamp.Color = 'white';
            app.T8Lamp.Color = 'white';
            app.P8Lamp.Color = 'white';
            app.P4Lamp.Color = 'white';
            app.O2Lamp.Color = 'white';
            app.PzLamp.Color = 'white';
            app.O1Lamp.Color = 'white';
            app.P3Lamp.Color = 'white';
            app.P7Lamp.Color = 'white';
            app.FT9Lamp.Color = 'white';
            app.FT10Lamp.Color = 'white';
        end

        % Value changed function: PatientDropDown, 
        % TotalDurationhoursEditField_2
        function PatientDropDownValueChanged(app, event)
            value = app.PatientDropDown.Value;
            modify_metadata(app,value);
        end

        % Value changed function: WindowsizeSlider
        function WindowsizeSliderValueChanged(app, event)
            value = floor(app.WindowsizeSlider.Value);
            if mod(value,2)
                value = value -1;
            end
            app.WindowsizeSlider.Value = value;
            app.EditField.Value = value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = floor(app.EditField.Value);
             if mod(value,2)
                value = value -1;
            end
            app.WindowsizeSlider.Value = value;
            app.EditField.Value = value;
        end

        % Value changed function: FP1F7CheckBox
        function FP1F7CheckBoxValueChanged(app, event)
            value = app.FP1F7CheckBox.Value;
            if value 
                app.FP1Lamp.Color = app.colorchange(app.FP1Lamp.Color,true);
                app.F7Lamp.Color = app.colorchange(app.F7Lamp.Color,true);
            else
                app.FP1Lamp.Color = app.colorchange(app.FP1Lamp.Color,false);
                app.F7Lamp.Color = app.colorchange(app.F7Lamp.Color,false);
            end
        end

        % Value changed function: F7T7CheckBox
        function F7T7CheckBoxValueChanged(app, event)
            value = app.F7T7CheckBox.Value;
            if value 
                app.T7Lamp.Color = app.colorchange(app.T7Lamp.Color,true);
                app.F7Lamp.Color = app.colorchange(app.F7Lamp.Color,true);
            else
                app.T7Lamp.Color = app.colorchange(app.T7Lamp.Color,false);
                app.F7Lamp.Color = app.colorchange(app.F7Lamp.Color,false);
            end
        end

        % Value changed function: T7P7CheckBox
        function T7P7CheckBoxValueChanged(app, event)
            value = app.T7P7CheckBox.Value;
            if value 
                app.T7Lamp.Color = app.colorchange(app.T7Lamp.Color,true);
                app.P7Lamp.Color = app.colorchange(app.P7Lamp.Color,true);
            else
                app.T7Lamp.Color = app.colorchange(app.T7Lamp.Color,false);
                app.P7Lamp.Color = app.colorchange(app.P7Lamp.Color,false);
            end
            
        end

        % Value changed function: P7O1CheckBox
        function P7O1CheckBoxValueChanged(app, event)
            value = app.P7O1CheckBox.Value;
            if value 
                app.O1Lamp.Color = app.colorchange(app.O1Lamp.Color,true);
                app.P7Lamp.Color = app.colorchange(app.P7Lamp.Color,true);
            else
                app.O1Lamp.Color = app.colorchange(app.O1Lamp.Color,false);
                app.P7Lamp.Color = app.colorchange(app.P7Lamp.Color,false);
            end
        end

        % Value changed function: C3P3CheckBox
        function C3P3CheckBoxValueChanged(app, event)
            value = app.C3P3CheckBox.Value;
            if value 
                app.C3Lamp.Color = app.colorchange(app.C3Lamp.Color,true);
                app.P3Lamp.Color = app.colorchange(app.P3Lamp.Color,true);
            else
                app.C3Lamp.Color = app.colorchange(app.C3Lamp.Color,false);
                app.P3Lamp.Color = app.colorchange(app.P3Lamp.Color,false);
            end
        end

        % Value changed function: P3O1CheckBox
        function P3O1CheckBoxValueChanged(app, event)
            value = app.P3O1CheckBox.Value;
            if value 
                app.P3Lamp.Color = app.colorchange(app.P3Lamp.Color,true);
                app.O1Lamp.Color = app.colorchange(app.O1Lamp.Color,true);
            else
                app.P3Lamp.Color = app.colorchange(app.P3Lamp.Color,false);
                app.O1Lamp.Color = app.colorchange(app.O1Lamp.Color,false);
            end
        end

        % Value changed function: FP2F4CheckBox
        function FP2F4CheckBoxValueChanged(app, event)
            value = app.FP2F4CheckBox.Value;
            if value 
                app.FP2Lamp.Color = app.colorchange(app.FP2Lamp.Color,true);
                app.F4Lamp.Color = app.colorchange(app.F4Lamp.Color,true);
            else
                app.FP2Lamp.Color = app.colorchange(app.FP2Lamp.Color,false);
                app.F4Lamp.Color = app.colorchange(app.F4Lamp.Color,false);
            end
        end

        % Value changed function: F4C4CheckBox
        function F4C4CheckBoxValueChanged(app, event)
            value = app.F4C4CheckBox.Value;
            if value 
                app.C4Lamp.Color = app.colorchange(app.C4Lamp.Color,true);
                app.F4Lamp.Color = app.colorchange(app.F4Lamp.Color,true);
            else
                app.C4Lamp.Color = app.colorchange(app.C4Lamp.Color,false);
                app.F4Lamp.Color = app.colorchange(app.F4Lamp.Color,false);
            end
        end

        % Value changed function: FP2F8CheckBox
        function FP2F8CheckBoxValueChanged(app, event)
            value = app.FP2F8CheckBox.Value;
            if value 
                app.FP2Lamp.Color = app.colorchange(app.FP2Lamp.Color,true);
                app.F8Lamp.Color = app.colorchange(app.F8Lamp.Color,true);
            else
                app.FP2Lamp.Color = app.colorchange(app.FP2Lamp.Color,false);
                app.F8Lamp.Color = app.colorchange(app.F8Lamp.Color,false);
            end
        end

        % Value changed function: F8T8CheckBox
        function F8T8CheckBoxValueChanged(app, event)
            value = app.F8T8CheckBox.Value;
            if value 
                app.T8Lamp.Color = app.colorchange(app.T8Lamp.Color,true);
                app.F8Lamp.Color = app.colorchange(app.F8Lamp.Color,true);
            else
                app.T8Lamp.Color = app.colorchange(app.T8Lamp.Color,false);
                app.F8Lamp.Color = app.colorchange(app.F8Lamp.Color,false);
            end
        end

        % Value changed function: T8P8CheckBox
        function T8P8CheckBoxValueChanged(app, event)
            value = app.T8P8CheckBox.Value;
            if value 
                app.T8Lamp.Color = app.colorchange(app.T8Lamp.Color,true);
                app.P8Lamp.Color = app.colorchange(app.P8Lamp.Color,true);
            else
                app.T8Lamp.Color = app.colorchange(app.T8Lamp.Color,false);
                app.P8Lamp.Color = app.colorchange(app.P8Lamp.Color,false);
            end            
        end

        % Value changed function: P8O2CheckBox
        function P8O2CheckBoxValueChanged(app, event)
            value = app.P8O2CheckBox.Value;
            if value 
                app.O2Lamp.Color = app.colorchange(app.O2Lamp.Color,true);
                app.P8Lamp.Color = app.colorchange(app.P8Lamp.Color,true);
            else
                app.O2Lamp.Color = app.colorchange(app.O2Lamp.Color,false);
                app.P8Lamp.Color = app.colorchange(app.P8Lamp.Color,false);
            end                     
        end

        % Callback function
        function P7T7CheckBoxValueChanged(app, event)
            value = app.P7T7CheckBox.Value;
            if value 
                app.T7Lamp.Color = app.colorchange(app.T7Lamp.Color,true);
                app.P7Lamp.Color = app.colorchange(app.P7Lamp.Color,true);
            else
                app.T7Lamp.Color = app.colorchange(app.T7Lamp.Color,false);
                app.P7Lamp.Color = app.colorchange(app.P7Lamp.Color,false);
            end                     
        end

        % Value changed function: T7FT9CheckBox
        function T7FT9CheckBoxValueChanged(app, event)
            value = app.T7FT9CheckBox.Value;
            if value 
                app.T7Lamp.Color = app.colorchange(app.T7Lamp.Color,true);
                app.FT9Lamp.Color = app.colorchange(app.FT9Lamp.Color,true);
            else
                app.T7Lamp.Color = app.colorchange(app.T7Lamp.Color,false);
                app.FT9Lamp.Color = app.colorchange(app.FT9Lamp.Color,false);
            end    
        end

        % Value changed function: FT9FT10CheckBox
        function FT9FT10CheckBoxValueChanged(app, event)
            value = app.FT9FT10CheckBox.Value;
            if value 
                app.FT10Lamp.Color = app.colorchange(app.FT10Lamp.Color,true);
                app.FT9Lamp.Color = app.colorchange(app.FT9Lamp.Color,true);
            else
                app.FT10Lamp.Color = app.colorchange(app.FT10Lamp.Color,false);
                app.FT9Lamp.Color = app.colorchange(app.FT9Lamp.Color,false);
            end    
        end

        % Value changed function: FT10T8CheckBox
        function FT10T8CheckBoxValueChanged(app, event)
            value = app.FT10T8CheckBox.Value;
            if value 
                app.FT10Lamp.Color = app.colorchange(app.FT10Lamp.Color,true);
                app.T8Lamp.Color = app.colorchange(app.T8Lamp.Color,true);
            else
                app.FT10Lamp.Color = app.colorchange(app.FT10Lamp.Color,false);
                app.T8Lamp.Color = app.colorchange(app.T8Lamp.Color,false);
            end                
        end

        % Value changed function: F3C3CheckBox
        function F3C3CheckBoxValueChanged(app, event)
            value = app.F3C3CheckBox.Value;
            if value 
                app.F3Lamp.Color = app.colorchange(app.F3Lamp.Color,true);
                app.C3Lamp.Color = app.colorchange(app.C3Lamp.Color,true);
            else
                app.F3Lamp.Color = app.colorchange(app.F3Lamp.Color,false);
                app.C3Lamp.Color = app.colorchange(app.C3Lamp.Color,false);
            end                
        end

        % Value changed function: P4O2CheckBox
        function P4O2CheckBoxValueChanged(app, event)
            value = app.P4O2CheckBox.Value;
            if value 
                app.P4Lamp.Color = app.colorchange(app.P4Lamp.Color,true);
                app.O2Lamp.Color = app.colorchange(app.O2Lamp.Color,true);
            else
                app.P4Lamp.Color = app.colorchange(app.P4Lamp.Color,false);
                app.O2Lamp.Color = app.colorchange(app.O2Lamp.Color,false);
            end            
        end

        % Value changed function: CZPZCheckBox
        function CZPZCheckBoxValueChanged(app, event)
            value = app.CZPZCheckBox.Value;
            if value 
                app.PzLamp.Color = app.colorchange(app.PzLamp.Color,true);
                app.CzLamp.Color = app.colorchange(app.CzLamp.Color,true);
            else
                app.PzLamp.Color = app.colorchange(app.PzLamp.Color,false);
                app.CzLamp.Color = app.colorchange(app.CzLamp.Color,false);
            end            
        end

        % Value changed function: FP1F3CheckBox
        function FP1F3CheckBoxValueChanged(app, event)
            value = app.FP1F3CheckBox.Value;
            if value 
                app.FP1Lamp.Color = app.colorchange(app.FP1Lamp.Color,true);
                app.F3Lamp.Color = app.colorchange(app.F3Lamp.Color,true);
            else
                app.FP1Lamp.Color = app.colorchange(app.FP1Lamp.Color,false);
                app.F3Lamp.Color = app.colorchange(app.F3Lamp.Color,false);
            end                     
        end

        % Value changed function: C4P4CheckBox
        function C4P4CheckBoxValueChanged(app, event)
            value = app.C4P4CheckBox.Value;
            if value 
                app.P4Lamp.Color = app.colorchange(app.P4Lamp.Color,true);
                app.C4Lamp.Color = app.colorchange(app.C4Lamp.Color,true);
            else
                app.P4Lamp.Color = app.colorchange(app.P4Lamp.Color,false);
                app.C4Lamp.Color = app.colorchange(app.C4Lamp.Color,false);
            end                     
        end

        % Value changed function: FZCZCheckBox
        function FZCZCheckBoxValueChanged(app, event)
            value = app.FZCZCheckBox.Value;
            if value 
                app.FzLamp.Color = app.colorchange(app.FzLamp.Color,true);
                app.CzLamp.Color = app.colorchange(app.CzLamp.Color,true);
            else
                app.FzLamp.Color = app.colorchange(app.FzLamp.Color,false);
                app.CzLamp.Color = app.colorchange(app.CzLamp.Color,false);
            end                     
        end

        % Button pushed function: CreateProfileButton
        function CreateProfileButtonPushed(app, event)
            channels = ["FP1-F7","F7-T7","T7-P7",...
                        "P7-O1","FP1-F3","F3-C3",...
                        "C3-P3","P3-O1","FP2-F4",...
                        "F4-C4","C4-P4","P4-O2",...
                        "FP2-F8","F8-T8","T8-P8",...
                        "P8-O2","FZ-CZ","CZ-PZ",...
                        "T7-FT9","FT9-FT10","FT10-T8"];
            channels_index = [app.FP1F7CheckBox.Value,app.F7T7CheckBox.Value, ...
                app.T7P7CheckBox.Value,app.P7O1CheckBox.Value,app.FP1F3CheckBox.Value,...
                app.F3C3CheckBox.Value,app.C3P3CheckBox.Value,app.P3O1CheckBox.Value,...
                app.FP2F4CheckBox.Value,app.F4C4CheckBox.Value,app.C4P4CheckBox.Value,...
                app.P4O2CheckBox.Value,app.FP2F8CheckBox.Value,app.F8T8CheckBox.Value,...
                app.T8P8CheckBox.Value,app.P8O2CheckBox.Value,app.FZCZCheckBox.Value,...
                app.CZPZCheckBox.Value,app.T7FT9CheckBox.Value,...
                app.FT9FT10CheckBox.Value,app.FT10T8CheckBox.Value];
            if ~any(channels_index)
                msgbox('Please select Channels','Error')
            else
                patient = app.PatientDropDown.Value;
                ch = channels(channels_index);
                window_size = app.EditField.Value;
                [~,file_name] = app.EEG_Profile(patient,ch,window_size);
                msgbox(sprintf('File save : %s',file_name),'Finish')   
                display_results_autoreflow(file_name)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGProfileCreatorUIFigure = uifigure('Visible', 'off');
            app.EEGProfileCreatorUIFigure.Name = 'EEG Profile Creator';
            app.PatientDropDownLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.PatientDropDownLabel.Text = 'Patient';
            app.PatientDropDown = uidropdown(app.EEGProfileCreatorUIFigure);
            app.PatientDropDown.Items = {'chb01', 'chb03', 'chb05', 'chb08', 'chb19', 'chb20', 'chb24'};
            app.PatientDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged, true);
            app.PatientDropDown.Value = 'chb01';
            app.Image = uiimage(app.EEGProfileCreatorUIFigure);
            app.Image.ImageSource = '20-10system - Copy.PNG';
            app.TotalDurationhoursEditField_2Label = uilabel(app.EEGProfileCreatorUIFigure);
            app.TotalDurationhoursEditField_2Label.Text = 'Total Duration (hours)';
            app.TotalDurationhoursEditField_2 = uieditfield(app.EEGProfileCreatorUIFigure, 'numeric');
            app.TotalDurationhoursEditField_2.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged, true);
            app.NumberofSeizureEditFieldLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.NumberofSeizureEditFieldLabel.Text = 'Number of Seizure';
            app.NumberofSeizureEditField = uieditfield(app.EEGProfileCreatorUIFigure, 'numeric');
            app.SeizureDurationsecondsEditFieldLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.SeizureDurationsecondsEditFieldLabel.Text = 'Seizure Duration (seconds)';
            app.SeizureDurationsecondsEditField = uieditfield(app.EEGProfileCreatorUIFigure, 'numeric');
            app.SeizureMeansecondsEditFieldLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.SeizureMeansecondsEditFieldLabel.Text = 'Seizure Mean (seconds)';
            app.SeizureMeansecondsEditField = uieditfield(app.EEGProfileCreatorUIFigure, 'numeric');
            app.FP1F7CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.FP1F7CheckBox.ValueChangedFcn = createCallbackFcn(app, @FP1F7CheckBoxValueChanged, true);
            app.FP1F7CheckBox.Text = 'FP1-F7';
            app.WindowsizeSliderLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.WindowsizeSliderLabel.Text = 'Window size';
            app.WindowsizeSlider = uislider(app.EEGProfileCreatorUIFigure);
            app.WindowsizeSlider.Limits = [256 4096];
            app.WindowsizeSlider.ValueChangedFcn = createCallbackFcn(app, @WindowsizeSliderValueChanged, true);
            app.WindowsizeSlider.Value = 256;
            app.EditField = uieditfield(app.EEGProfileCreatorUIFigure, 'numeric');
            app.EditField.Limits = [256 4096];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 256;
            app.C3P3CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.C3P3CheckBox.ValueChangedFcn = createCallbackFcn(app, @C3P3CheckBoxValueChanged, true);
            app.C3P3CheckBox.Text = 'C3-P3';
            app.FP2F8CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.FP2F8CheckBox.ValueChangedFcn = createCallbackFcn(app, @FP2F8CheckBoxValueChanged, true);
            app.FP2F8CheckBox.Text = 'FP2-F8';
            app.F7T7CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.F7T7CheckBox.ValueChangedFcn = createCallbackFcn(app, @F7T7CheckBoxValueChanged, true);
            app.F7T7CheckBox.Text = 'F7-T7';
            app.P3O1CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.P3O1CheckBox.ValueChangedFcn = createCallbackFcn(app, @P3O1CheckBoxValueChanged, true);
            app.P3O1CheckBox.Text = 'P3-O1';
            app.F8T8CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.F8T8CheckBox.ValueChangedFcn = createCallbackFcn(app, @F8T8CheckBoxValueChanged, true);
            app.F8T8CheckBox.Text = 'F8-T8';
            app.T7FT9CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.T7FT9CheckBox.ValueChangedFcn = createCallbackFcn(app, @T7FT9CheckBoxValueChanged, true);
            app.T7FT9CheckBox.Text = 'T7-FT9';
            app.T7P7CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.T7P7CheckBox.ValueChangedFcn = createCallbackFcn(app, @T7P7CheckBoxValueChanged, true);
            app.T7P7CheckBox.Text = 'T7-P7';
            app.FP2F4CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.FP2F4CheckBox.ValueChangedFcn = createCallbackFcn(app, @FP2F4CheckBoxValueChanged, true);
            app.FP2F4CheckBox.Text = 'FP2-F4';
            app.T8P8CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.T8P8CheckBox.ValueChangedFcn = createCallbackFcn(app, @T8P8CheckBoxValueChanged, true);
            app.T8P8CheckBox.Text = 'T8-P8';
            app.FT9FT10CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.FT9FT10CheckBox.ValueChangedFcn = createCallbackFcn(app, @FT9FT10CheckBoxValueChanged, true);
            app.FT9FT10CheckBox.Text = 'FT9-FT10';
            app.P7O1CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.P7O1CheckBox.ValueChangedFcn = createCallbackFcn(app, @P7O1CheckBoxValueChanged, true);
            app.P7O1CheckBox.Text = 'P7-O1';
            app.F4C4CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.F4C4CheckBox.ValueChangedFcn = createCallbackFcn(app, @F4C4CheckBoxValueChanged, true);
            app.F4C4CheckBox.Text = 'F4-C4';
            app.P8O2CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.P8O2CheckBox.ValueChangedFcn = createCallbackFcn(app, @P8O2CheckBoxValueChanged, true);
            app.P8O2CheckBox.Text = 'P8-O2';
            app.FT10T8CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.FT10T8CheckBox.ValueChangedFcn = createCallbackFcn(app, @FT10T8CheckBoxValueChanged, true);
            app.FT10T8CheckBox.Text = 'FT10-T8';
            app.F3C3CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.F3C3CheckBox.ValueChangedFcn = createCallbackFcn(app, @F3C3CheckBoxValueChanged, true);
            app.F3C3CheckBox.Text = 'F3-C3';
            app.P4O2CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.P4O2CheckBox.ValueChangedFcn = createCallbackFcn(app, @P4O2CheckBoxValueChanged, true);
            app.P4O2CheckBox.Text = 'P4-O2';
            app.CZPZCheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.CZPZCheckBox.ValueChangedFcn = createCallbackFcn(app, @CZPZCheckBoxValueChanged, true);
            app.CZPZCheckBox.Text = 'CZ-PZ';
            app.F7LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.F7LampLabel.Text = 'F7';
            app.F7Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.FP1LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.FP1LampLabel.Text = 'FP1';
            app.FP1Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.F3LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.F3LampLabel.Text = 'F3';
            app.F3Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.T7LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.T7LampLabel.Text = 'T7';
            app.T7Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.C3LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.C3LampLabel.Text = 'C3';
            app.C3Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.FzLampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.FzLampLabel.Text = 'Fz';
            app.FzLamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.FP2LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.FP2LampLabel.Text = 'FP2';
            app.FP2Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.F8LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.F8LampLabel.Text = 'F8';
            app.F8Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.F4LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.F4LampLabel.Text = 'F4';
            app.F4Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.CzLampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.CzLampLabel.Text = 'Cz';
            app.CzLamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.C4LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.C4LampLabel.Text = 'C4';
            app.C4Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.T8LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.T8LampLabel.Text = 'T8';
            app.T8Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.P8LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.P8LampLabel.Text = 'P8';
            app.P8Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.P4LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.P4LampLabel.Text = 'P4';
            app.P4Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.O2LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.O2LampLabel.Text = 'O2';
            app.O2Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.PzLampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.PzLampLabel.Text = 'Pz';
            app.PzLamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.O1LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.O1LampLabel.Text = 'O1';
            app.O1Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.P3LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.P3LampLabel.Text = 'P3';
            app.P3Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.P7LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.P7LampLabel.Text = 'P7';
            app.P7Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.FT10LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.FT10LampLabel.Text = 'FT10';
            app.FT10Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.FT9LampLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.FT9LampLabel.Text = 'FT9';
            app.FT9Lamp = uilamp(app.EEGProfileCreatorUIFigure);
            app.FP1F3CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.FP1F3CheckBox.ValueChangedFcn = createCallbackFcn(app, @FP1F3CheckBoxValueChanged, true);
            app.FP1F3CheckBox.Text = 'FP1-F3';
            app.C4P4CheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.C4P4CheckBox.ValueChangedFcn = createCallbackFcn(app, @C4P4CheckBoxValueChanged, true);
            app.C4P4CheckBox.Text = 'C4-P4';
            app.FZCZCheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.FZCZCheckBox.ValueChangedFcn = createCallbackFcn(app, @FZCZCheckBoxValueChanged, true);
            app.FZCZCheckBox.Text = 'FZ-CZ';
            app.CreateProfileButton = uibutton(app.EEGProfileCreatorUIFigure, 'push');
            app.CreateProfileButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProfileButtonPushed, true);
            app.CreateProfileButton.Text = 'Create Profile';
            app.ChannelsLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.ChannelsLabel.Text = 'Channels:';
            app.EEGProfileCreatorLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.EEGProfileCreatorLabel.Text = 'EEG Profile Creator';
            app.developbySonyaZadigolinVikiFadlonLabel = uilabel(app.EEGProfileCreatorUIFigure);
            app.developbySonyaZadigolinVikiFadlonLabel.Text = 'develop by: Sonya Zadigolin & Viki Fadlon';
            app.FilternoisychannelsCheckBox = uicheckbox(app.EEGProfileCreatorUIFigure);
            app.FilternoisychannelsCheckBox.Text = 'Filter noisy channels';
            % Show the figure after all components are created
            app.EEGProfileCreatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_Profile_Creator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGProfileCreatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGProfileCreatorUIFigure)
        end
    end
end