classdef display_results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ClassifierButtonGroup  matlab.ui.container.ButtonGroup
        SVMButton              matlab.ui.control.RadioButton
        KNNButton              matlab.ui.control.RadioButton
        LDRButton              matlab.ui.control.RadioButton
        Panel                  matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Results % Description
    end
    
    methods (Access = private)
        
        function display_results_func(app)
            class = app.ClassifierButtonGroup.SelectedObject.Text;
            if class == "SVM"
                data = app.Results(:,1)';
            elseif class == "KNN"
                data = app.Results(:,2)';
            else
                data = app.Results(:,3)';
            end
            predict =  app.Results(:,4)';
            confusionchart(app.Panel,data,predict)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mat_file)
            load(mat_file,'results');
            app.Results = results;
            app.display_results_func()
        end

        % Selection changed function: ClassifierButtonGroup
        function ClassifierButtonGroupSelectionChanged(app, event)
            app.display_results_func()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ClassifierButtonGroup = uibuttongroup(app.UIFigure);
            app.ClassifierButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClassifierButtonGroupSelectionChanged, true);
            app.ClassifierButtonGroup.Title = 'Classifier:';
            app.SVMButton = uiradiobutton(app.ClassifierButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.SVMButton.Value = true;
            app.KNNButton = uiradiobutton(app.ClassifierButtonGroup);
            app.KNNButton.Text = 'KNN';
            app.LDRButton = uiradiobutton(app.ClassifierButtonGroup);
            app.LDRButton.Text = 'LDR';
            app.Panel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = display_results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end